classdef DigitalTwin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DigitalTwinModelLabel  matlab.ui.control.Label
        MainButton             matlab.ui.control.Button
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        newd={};% Description
        
        newa=[]; % Description
        model; % Description
        DTTimer; % Description
        inputwindow=[]; % Description
        fulldata=[] % Description
         % Description
        predhis=[] % Description
        timehis=[] % Description
        anomalyhis=[] % Description
    end
    
    methods (Access = private)
        
        function predictdata(app)
            
            windowsize=10;
            
            [value,~]=getdata();   
            
            app.fulldata=[app.fulldata, value];

             app.inputwindow=[app.inputwindow, value];  %implementewd sliding window concept because live values are coming one by one
           try  
             if length(app.inputwindow)>windowsize
                app.inputwindow=app.inputwindow(end-windowsize+1:end);
             end
             if length(app.inputwindow)<windowsize
                    return;
             end
            
            if length(app.inputwindow)>=windowsize
                input=(app.inputwindow-mean(app.inputwindow))/std(app.inputwindow);
                
                 Predval1 = predmodel(app.model, input);
            end
           catch
               disp("please wait")
           end
          
             
              Predval1=Predval1*std(app.inputwindow)+mean(app.inputwindow);
              %in=app.inputwindow(:) Predval1(:)])
               if length(Predval1)>=windowsize
                 app.predhis=[app.predhis, Predval1(1)];
               end
              %for i = 1:(length(app.fulldata) - windowsize + 1)

               %app.predhis(end+1) = Predval1(end);

              %end 


              deviation=app.predhis - app.fulldata(1:length(app.predhis));
              %disp(deviation) 
              %threshold = prctile(abs(deviation), 90);
               %disp(threshold)
               % anomaly condition (robust)
             %threshold = 1.4 * std(deviation);
             threshold = prctile(abs(deviation), 99);

             anomalyidx = find(abs(deviation) > threshold);
             %anomalyidx=find(abs(deviation)>threshold);
        

              % disp(anomalyidx)
              %threshold = mean(deviation) + 2*std(deviation);
              %threshold=12;
              %if deviation(end) > threshold
               %    anomalies = length(app.inputwindow);   % only last point
              %else
               %    anomalies = [];
              %end
              %anomalies=find(abs(deviation)>threshold);
              %disp("Anomalies:"), disp(anomalies)
                
              %app.newa(end+1)=anomalies;
              cla (app.UIAxes);
           % plot(app.UIAxes,app.inputwindow);
               hold(app.UIAxes, 'off')
               %plot(app.UIAxes,Predval1)
                % Plot real data (growing)
               plot(app.UIAxes3, app.fulldata, 'b');
                % hold(app.UIAxes, 'on')
              % Plot prediction aligned to last window
              startIdx = length(app.fulldata) - windowsize+1 ;
               xPred = startIdx : length(app.fulldata);
               app.timehis=[app.timehis, xPred(1)];

               plot(app.UIAxes, xPred, Predval1, 'r');
               
               %startidx=length(xPred)-windowsize+1;
               %anmidx=startidx:length(xPred);
               %disp(anmidx)


               hold(app.UIAxes, 'off')
               % Map anomalies to the full dataset
                 
                 plot(app.UIAxes2,app.timehis, app.predhis)
                 hold(app.UIAxes2, 'on')
                
                 %xlim(app.UIAxes2, [1 length(app.fulldata)])
                 
                %  startIdx = length(app.fulldata) - windowsize + 1;

                % Keep only anomalies inside current window
               % local_idx = anomalyidx(anomalyidx >= startIdx & anomalyidx <= length(app.fulldata));

                % Convert to window indices (1–10)
                 % local_idx = local_idx - startIdx + 1;
                % app.anomalyhis=[app.anomalyhis; anmidx(1)];
                  % if ~isempty(local_idx)
                 scatter(app.UIAxes2,app.timehis(anomalyidx),app.predhis(anomalyidx), 'r') 
                 hold(app.UIAxes2, 'off')
                   
                      %anomalyidx = anomalyidx + startIdx - 1;
               %        disp(local_idx)
                     
                %   scatter(app.UIAxes2, xPred(local_idx), app.fulldata(local_idx),'go', 'Markersize',6,'filled');
                      %plot(app.UIAxes2, anomalies)
               
               title(app.UIAxes, 'ML based Predictions')
               title(app.UIAxes2, 'Predicted System & Anomalies');  
               xlabel(app.UIAxes2, 'Time')
               ylabel(app.UIAxes2, 'Sensor values')
               title(app.UIAxes3, 'Actual Data Feed');
               xlabel(app.UIAxes,'Time');
               ylabel(app.UIAxes, 'Sensor values');
              drawnow limitrate
      end


        function loadModel(app)

          data = load('lstmmodel.mat');    %model should be trained without anomalies
          app.model = data.model;

        end
        
        
        function safepredictdata(app)
            if isempty(app) || ~isvalid(app) || ~isvalid(app.UIAxes)
                return;
            end
            predictdata(app)
        end
        
        function closeApp(app)

    % Stop timer safely
          if ~isempty(app.DTTimer)
             try
                 if isvalid(app.DTTimer)
                    stop(app.DTTimer);
                    delete(app.DTTimer);
                 end
            catch
            end
         end

    % Delete UI
    delete(app.UIFigure);

   end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear getdata;
            if isempty(app.model)
             loadModel(app);
            end
            % Now your digital twin logic can run
           disp("Model loaded and ready");
           app.DTTimer=timer( 'ExecutionMode', 'fixedrate', 'Period',2, ...
                'BusyMode', 'drop','TimerFcn', @(~,~)safepredictdata(app));
            start(app.DTTimer);
            app.UIFigure.CloseRequestFcn = @(src,event) closeApp(app);  %to start the closeup func to avoid crash
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            % Stop timer safely
            if ~isempty(app.DTTimer)
                try
                   if isvalid(app.DTTimer)
                     stop(app.DTTimer);
                     delete(app.DTTimer);
                   end
                catch
            
                end
            end

    % Close app
           delete(app.UIFigure);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = 'Main';
            app.DigitalTwinModelLabel = uilabel(app.UIFigure);
            app.DigitalTwinModelLabel.Text = 'Digital Twin Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DigitalTwin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end