classdef Mainpage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitalTwinDashboardandPredictiveMaintenanceUIFigure  matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        DigitalTwinSimulationButton  matlab.ui.control.Button
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        btm                          matlab.ui.control.Label
        MaintenanceAnalysisButton    matlab.ui.control.Button
        UploadDatasetButton          matlab.ui.control.Button
        RealTimeMonitoringButton     matlab.ui.control.Button
        PredictiveMaintenanceandDigitalTwinDashboardLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
       AutoResizeChildren; % Description
       model;
         % Description
         % Description
        BlinkTimer % Description
    end
    
    methods (Access = private)


        function blinklabel(app)
            if strcmp(app.btm.Visible, 'on')
                app.btm.Visible='off';
            else
                app.btm.Visible='on';
           end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.BlinkTimer=timer('Execution', 'fixedrate', 'Period', 1,'Timerfcn', @(~,~)blinklabel(app));
             start(app.BlinkTimer);

        end

        % Button pushed function: RealTimeMonitoringButton
        function RealTimeMonitoringButtonPushed(app, event)
            Realtime;


        end

        % Button pushed function: DigitalTwinSimulationButton
        function DigitalTwinSimulationButtonPushed(app, event)
            DigitalTwin();
             % Load model only once


        end

        % Button pushed function: UploadDatasetButton
        function UploadDatasetButtonPushed(app, event)
            UploadData();
        end

        % Button pushed function: MaintenanceAnalysisButton
        function MaintenanceAnalysisButtonPushed(app, event)
            MaintenanceAnalysis();
        end

        % Close request function: 
        % DigitalTwinDashboardandPredictiveMaintenanceUIFigure
        function DigitalTwinDashboardandPredictiveMaintenanceUIFigureCloseRequest(app, event)
            
          if isvalid(app.BlinkTimer)
            stop(app.BlinkTimer);
            delete(app.BlinkTimer);
          end
       delete(app); % Close the app


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure = uifigure('Visible', 'off');
            app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure.Name = 'Digital Twin Dashboard and Predictive Maintenance';
            app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure.CloseRequestFcn = createCallbackFcn(app, @DigitalTwinDashboardandPredictiveMaintenanceUIFigureCloseRequest, true);
            app.Menu = uimenu(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.Menu.Text = 'Menu';
            app.PredictiveMaintenanceandDigitalTwinDashboardLabel = uilabel(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.PredictiveMaintenanceandDigitalTwinDashboardLabel.Text = 'Predictive Maintenance and Digital Twin Dashboard';
            app.RealTimeMonitoringButton = uibutton(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure, 'push');
            app.RealTimeMonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @RealTimeMonitoringButtonPushed, true);
            app.RealTimeMonitoringButton.Text = 'Real Time Monitoring';
            app.UploadDatasetButton = uibutton(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure, 'push');
            app.UploadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDatasetButtonPushed, true);
            app.UploadDatasetButton.Text = 'Upload Dataset';
            app.MaintenanceAnalysisButton = uibutton(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure, 'push');
            app.MaintenanceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @MaintenanceAnalysisButtonPushed, true);
            app.MaintenanceAnalysisButton.Text = 'Maintenance Analysis';
            app.btm = uilabel(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.btm.Text = 'Please select an option to continue';
            app.Image = uiimage(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'flat-graph-chart-icon-symbol-vector-illustration_1026019-3531 (1).png');
            app.Image2 = uiimage(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '10228993.png');
            app.Image3 = uiimage(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'upload (2).jpg');
            app.Image4 = uiimage(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '4826326.jpg');
            app.DigitalTwinSimulationButton = uibutton(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure, 'push');
            app.DigitalTwinSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DigitalTwinSimulationButtonPushed, true);
            app.DigitalTwinSimulationButton.Text = 'Digital Twin Simulation';
            % Show the figure after all components are created
            app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mainpage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitalTwinDashboardandPredictiveMaintenanceUIFigure)
        end
    end
end