classdef MaintenanceAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConditionLabel                matlab.ui.control.Label
        NoiseLabel                    matlab.ui.control.Label
        ForecastHorizonDropDown       matlab.ui.control.DropDown
        ForecastHorizonDropDownLabel  matlab.ui.control.Label
        RunAnalysisButton             matlab.ui.control.Button
        Filepath                      matlab.ui.control.Label
        AttachButton                  matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        LampLabel                     matlab.ui.control.Label
        DriftLabel                    matlab.ui.control.Label
        SensorHealthGauge             matlab.ui.control.Gauge
        SensorHealthLabel             matlab.ui.control.Label
        MainButton                    matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ForecastTimer;% Description
        model;
        
        loadeddata; % Description
        FilePath % Description
        fulldata % Description
        Mode % Description
        window % Description
        batch % Description
        HealthLamp % Description
        healthHis=[] % Description
        timeHis=[]
    end
    
    methods (Access = private)
        
        function loadModel(app)
            
               data1=load('lstmmodel.mat');
               app.model=data1.model;


         end
        
        function forecast(app)
            
             [app.window, app.batch] = getUserSettings(app);

             if isempty(app.loadeddata)                     %if its empty
                 return;
             end

             if ~isnumeric(app.loadeddata)               %because if class is not double of loaded data
                disp("Data is not numeric");
                return;
             end

            value=app.loadeddata;
             if std(value) == 0                   %avoid infinite value
                     disp("Std is zero, skipping...");
                    return;
              end
              % Remove NaN or Inf
            value = value(~isnan(value) & ~isinf(value));                  %if the values are correct

            % Ensure column vector
            value = value(:);   %column vector

            if isempty(value)
                disp("Data became empty after cleaning");
                return;
            end
            app.fulldata=value;

            input_seq = (app.fulldata - mean(app.fulldata)) / std(app.fulldata);
            
            [forecast,time]=forcastanalysis(app.model, input_seq, app.window, app.batch);
            forecast=forecast*std(app.fulldata)+mean(app.fulldata);
                 %Plot
            plot(app.UIAxes2,time, forecast','b')
            title(app.UIAxes2, 'ML Forecast Predictions')
            xlabel(app.UIAxes2,'Time')
            ylabel(app.UIAxes2, 'Sensor Values')
            %if iscell(forecast)
             %   forecast=forecast{1};
            %end
            %forecast=double(forecast);
            %fprintf('forecast size: %dx%d\n', size(forecast,1), size(forecast,2));
            %fprintf('time size: %dx%d\n', size(time,1), size(time,2));
            %uialert(app.UIFigure, ...
             %   sprintf('forecast: %dx%d | time: %dx%d', ...
              %  size(forecast,1), size(forecast,2), ...
               % size(time,1), size(time,2)), 'Debug Info');

              drift = mean(forecast(end-10:end)) - mean(forecast(1:10));

              if abs(drift)<0.5
                  status='LOW';
              elseif abs(drift)<2.0
                      status='MEDIUM';
              else
                      status='HIGH';
              end

            
                app.DriftLabel.Text = "Drift: " + status;
               
                noise=std(forecast);

                if noise < 0.5
                     noiseStatus = "STABLE";
                elseif noise < 2
                        noiseStatus = "NOISY";
                else
                    noiseStatus = "UNSTABLE";
                end

                 app.NoiseLabel.Text = "Noise: " + noiseStatus;
                 drift_max=5;
                 noise_max=5;
                 drift_norm = min(abs(drift)/drift_max, 1);
                 noise_norm = min(noise/noise_max, 1);
                 health = 100 * (1 - (0.6*drift_norm + 0.4*noise_norm));
                 app.SensorHealthGauge.Value = health;

                 % Update lamp
                if health > 80
                    app.HealthLamp.Color = [0 1 0];
                    status = "Healthy";
                elseif health > 50
                     app.HealthLamp.Color = [1 1 0];
                     status = "Warning";
                else
                    app.HealthLamp.Color = [1 0 0];
                    status = "Critical";
                end

                % Optional label
                app.ConditionLabel.Text =status;

                app.healthHis(end+1)=health;
                app.timeHis(end+1)=length(app.timeHis) + 1;
                plot(app.UIAxes, app.timeHis,app.healthHis,'r','Linewidth',1.5)
                title(app.UIAxes, 'Sensor Health over Time')
                xlabel(app.UIAxes, 'Time')
                ylabel(app.UIAxes, 'Health')
            
                
    end
        
        function [window, batch] = getUserSettings(app)
            
            mode=app.ForecastHorizonDropDown.Value;
            
            switch mode 
                case "Fast"
                    window=100;
                    batch=50;
                case "Balanced"
                    window=150;
                    batch=100;
                case "Accurate"
                    window =200;
                    batch =200;
             
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.loadeddata
               app.ForecastTimer=timer('ExecutionMode', 'fixedrate', 'period',2, ...
                'BusyMode', 'drop','TimerFcn',@(~,~)forecast(app));
               start(app.ForecastTimer);
            
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            stop(app.ForecastTimer);
            delete(app);
        end

        % Button pushed function: AttachButton
        function AttachButtonPushed(app, event)
           % Open file selection dialog
           [file, path] = uigetfile({'*.csv;*.txt', 'Data Files (*.csv, *.txt)'}, ...
                                'Select Data File');
    
    % Check if user did not cancel
            if isequal(file,0)
               disp('No file selected');
               return;
            end

    % Get full path
            fullFileName = fullfile(path, file);
            app.Filepath.Text = ['Loaded: ', file, path];
           app.Filepath.FontColor = [0 0.5 0];

    % Load the file
            try
              data = readmatrix(fullFileName);  % for numeric files
              % Or: data = readtable(fullFileName); % if you want tables
            catch
                uialert(app.UIFigure, 'Error loading file. Check file format.', 'File Error');
               return;
            end
    

         % Store loaded data in app property
            app.loadeddata = data;  % Make sure you created a property 'loadedData'

          disp(class(app.loadeddata))
            % Display confirmation
            disp(['File loaded: ', fullFileName]);
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            if isempty(app.model)
                loadModel(app)
            end
            disp("Model loaded and ready")
           forecast(app);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = 'Main';
            app.SensorHealthLabel = uilabel(app.UIFigure);
            app.SensorHealthLabel.Text = 'Sensor Health %';
            app.SensorHealthGauge = uigauge(app.UIFigure, 'circular');
            app.DriftLabel = uilabel(app.UIFigure);
            app.DriftLabel.Text = 'Drift';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.AttachButton = uibutton(app.UIFigure, 'push');
            app.AttachButton.ButtonPushedFcn = createCallbackFcn(app, @AttachButtonPushed, true);
            app.AttachButton.Text = 'Attach';
            app.Filepath = uilabel(app.UIFigure);
            app.Filepath.Text = '''No file selected''';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ForecastHorizonDropDownLabel = uilabel(app.UIFigure);
            app.ForecastHorizonDropDownLabel.Text = 'Forecast Horizon';
            app.ForecastHorizonDropDown = uidropdown(app.UIFigure);
            app.ForecastHorizonDropDown.Items = {'Fast', 'Balanced', 'Accurate'};
            app.ForecastHorizonDropDown.Value = 'Fast';
            app.NoiseLabel = uilabel(app.UIFigure);
            app.NoiseLabel.Text = 'Noise';
            app.ConditionLabel = uilabel(app.UIFigure);
            app.ConditionLabel.Text = 'Condition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaintenanceAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end