classdef Realtime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LiveDataStreamLabel  matlab.ui.control.Label
        MainButton           matlab.ui.control.Button
        Gauge                matlab.ui.control.Gauge
        GaugeLabel           matlab.ui.control.Label
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RealtimeTimer % Description
        data=[] % Description
        anomalies=[] % Description
    end
    
    methods (Access = private)
        function UpdateData(app)
          % Get new value from your .m file
           [value,anomaly] = getdata();  % call your data function
           
           % Store in app data array
           app.data(end+1) = value;
           if ~isempty(app.anomalies)
               app.anomalies(end+1)=anomaly;
           else
               app.anomalies=NaN;
           end
           % Update plot
           plot(app.UIAxes, app.data, 'b');
           xlabel(app.UIAxes, 'Time');
           ylabel(app.UIAxes, 'Sensor Readings');
           title(app.UIAxes, 'Live Monitoring')

           plot(app.UIAxes2, app.anomalies, '-o', 'MarkerFaceColor', 'r');
           xlabel(app.UIAxes2, 'Time');
           ylabel(app.UIAxes2, 'Sensor Readings');
           title(app.UIAxes2, 'Live Anomaly')
           % Update gauge
           app.Gauge.Value = value;
               % Force immediate redraw
           drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear getdata;
            app.RealtimeTimer=timer( 'ExecutionMode', 'fixedrate', 'period',1, ...
                'TimerFcn', @(~,~)UpdateData(app));
            start(app.RealtimeTimer);
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            stop(app.RealtimeTimer);
            delete (app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = 'Main';
            app.LiveDataStreamLabel = uilabel(app.UIFigure);
            app.LiveDataStreamLabel.Text = 'Live Data Stream';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Realtime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end