classdef UploadData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FilePath           matlab.ui.control.Label
        AttachButton       matlab.ui.control.Button
        RunDigiTwinButton  matlab.ui.control.Button
        MainButton         matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         loadeddata; % Description
         model % Description
         fulldata=[] % Description
         UploadDatatimer % Description
         Anomalymodel % Description
    end
    
    methods (Access = private)
        
        function predictdata(app)
            
             if isempty(app.loadeddata)                     %if its empty
                 return;
             end

             if ~isnumeric(app.loadeddata)               %because if class is not double of loaded data
                disp("Data is not numeric");
                return;
             end

              value = app.loadeddata;

            % Remove NaN or Inf
            value = value(~isnan(value) & ~isinf(value));                  %if the values are correct

            % Ensure column vector
            value = value(:);   %column vector

            if isempty(value)
                disp("Data became empty after cleaning");
                return;
           end

            app.fulldata = value;
                if std(app.fulldata) == 0                   %avoid infinite value
                     disp("Std is zero, skipping...");
                    return;
                end

               input_seq = (app.fulldata - mean(app.fulldata)) / std(app.fulldata);

               input_norm=input_seq';
               Predval1 = predmodel(app.model, input_norm);
               Predval1 = Predval1 * std(app.fulldata) + mean(app.fulldata);
               %isAnomaly=isanomalies(app.Anomalymodel, Predval1);
                %anomalyidx=find(isAnomaly);


               deviation=app.fulldata-Predval1(:);
              % disp(deviation);
               %threshold=0.96*abs(deviation);
               threshold = prctile(abs(deviation), 97.2);
               disp(threshold);
               anomalyidx=find(abs(deviation)>threshold);
               disp(anomalyidx);
               cla(app.UIAxes);
               cla(app.UIAxes2);

             
               plot(app.UIAxes2, app.fulldata, 'b');
               %hold(app.UIAxes, 'on');
	           
                %plot(app.UIAxes, find(isAnomaly), inputCol(isAnomaly), 'ro');
               %plot(app.UIAxes2, app.fulldata(isAnomaly), 'ro');

               title(app.UIAxes2, 'Actual Data');
                %scatter(app.UIAxes2,anomalyidx,  app.fulldata(anomalyidx), ...
                 % 60, 'r', 'filled');
                    plot(app.UIAxes, Predval1, 'r');
               title(app.UIAxes, 'Predicted Behavior & Anomalies');
               xlabel(app.UIAxes, 'Time')
               xlabel(app.UIAxes2, 'Time')
               ylabel(app.UIAxes, 'Sensor values')
               ylabel(app.UIAxes2, 'Sensor values')
               
                hold(app.UIAxes, 'on');
             scatter(app.UIAxes,anomalyidx,  Predval1(anomalyidx), ...
                  30, 'green', 'filled');
                
                %plot(app.UIAxes,Predval1(isAnomaly),'-o')
     %       value=app.loadeddata;
            
      %      app.fulldata=value;

            
       %       input_norm=(app.fulldata-mean(app.fulldata))/std(app.fulldata);
        %      Predval1 = predmodel(app.model, input_norm);
         %     Predval1=Predval1*std(app.fulldata)+mean(app.fulldata);
              %in=app.inputwindow(:) Predval1(:)])
              %deviation=app.fulldata(:) - Predval1(:);
              %threshold = mean(deviation) + 2*std(deviation);
              %threshold=12;
              %if deviation(end) > threshold
               %    anomalies = length(app.inputwindow);   % only last point
              %else
               %    anomalies = [];
              %end
             % anomalies=find(abs(deviation)>threshold);
              %disp("Anomalies:"), disp(anomalies)
                
              %app.newa(end+1)=anomalies;
          %    cla (app.UIAxes);
           % plot(app.UIAxes,app.inputwindow);
           %    hold(app.UIAxes, 'off')
               %plot(app.UIAxes,Predval1)
                % Plot real data (growing)
            %   plot(app.UIAxes2, app.fulldata, 'b');
                % hold(app.UIAxes, 'on')
              % Plot prediction aligned to last window
              %startIdx = length(app.fulldata) - windowsize+1 ;
               %xPred = startIdx : length(app.fulldata);

             %  plot(app.UIAxes, Predval1, 'r');
               % Map anomalies to the full dataset


               %if ~isempty(anomalies)
                %     anomalies = anomalies(anomalies <= length(Predval1));
                 %       anomalyIdx = anomalies + startIdx - 1;
                  %   plot(app.UIAxes2, anomalyIdx, app.fulldata(anomalyIdx),'-ro');
                      %plot(app.UIAxes2, anomalies)
               %end
              % title(app.UIAxes, 'ML based Predicted System Behaviour')
               %title(app.UIAxes2, 'Predicted System Anomalies')
               %title(app.UIAxes3, 'Actual Data Feed')
               %xlabel(app.UIAxes,'Time')
               %ylabel(app.UIAxes, 'Sensor Normalised values')
              drawnow limitrate;


        end
        
        function loadModel(app)
            data1=load('lstmmodel.mat');
            app.model=data1.model;   %resulted model has segment which is accepted for prediction, called 
                                      %explicitly as data.model instead of
                                      %data
        end


       %function loadAnomalymodel(app)
          
              
        %        data2=load('anomalies.mat');
         %       app.Anomalymodel=data2.autoenc;
            
      %  end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear app.fulldata
             
           app.UploadDatatimer=timer( 'ExecutionMode', 'fixedrate', 'period',2, ...
                'BusyMode', 'drop','TimerFcn', @(~,~)predictdata(app)); %busymode can avoid any data missing
            start(app.UploadDatatimer); %timer is started when app starts but data is not loaded 
                                           %can create issue, therefore
                                           %predict func. data is loaded
                                           %carefully

        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            stop(app.UploadDatatimer)
            delete(app);
        end

        % Button pushed function: AttachButton
        function AttachButtonPushed(app, event)
                  
    % Open file selection dialog
           [file, path] = uigetfile({'*.csv;*.txt', 'Data Files (*.csv, *.txt)'}, ...
                                'Select Data File');
    
    % Check if user did not cancel
            if isequal(file,0)
               disp('No file selected');
               return;
            end

    % Get full path
            fullFileName = fullfile(path, file);
            app.FilePath.Text = ['Loaded: ', file, path];
           app.FilePath.FontColor = [0 0.5 0];

    % Load the file
            try
              data = readmatrix(fullFileName);  % for numeric files
              % Or: data = readtable(fullFileName); % if you want tables
            catch
                uialert(app.UIFigure, 'Error loading file. Check file format.', 'File Error');
               return;
            end
    

         % Store loaded data in app property
            app.loadeddata = data;  % Make sure you created a property 'loadedData'

          disp(class(app.loadeddata))
            % Display confirmation
            disp(['File loaded: ', fullFileName]);


        end

        % Button pushed function: RunDigiTwinButton
        function RunDigiTwinButtonPushed(app, event)
            if isempty(app.model) %to avoid any crash
             loadModel(app);
            end
            %if isempty(app.Anomalymodel)
             %   loadAnomalymodel(app);
            %end
 %            Now your digital twin logic can run
            disp("Model loaded and ready");
            predictdata(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = 'Main';
            app.RunDigiTwinButton = uibutton(app.UIFigure, 'push');
            app.RunDigiTwinButton.ButtonPushedFcn = createCallbackFcn(app, @RunDigiTwinButtonPushed, true);
            app.RunDigiTwinButton.Text = 'Run DigiTwin';
            app.AttachButton = uibutton(app.UIFigure, 'push');
            app.AttachButton.ButtonPushedFcn = createCallbackFcn(app, @AttachButtonPushed, true);
            app.AttachButton.Text = 'Attach';
            app.FilePath = uilabel(app.UIFigure);
            app.FilePath.Text = '             ''No FIle Selected''';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UploadData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end